classdef ActiveContoursApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        AlgorithmDropDownLabel         matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        MethodButtonGroup              matlab.ui.container.ButtonGroup
        RegionButton                   matlab.ui.control.RadioButton
        EdgeButton                     matlab.ui.control.RadioButton
        CropButton                     matlab.ui.control.Button
        ROIButton                      matlab.ui.control.Button
        ImageFromlistListBoxLabel      matlab.ui.control.Label
        ImageFromlist                  matlab.ui.control.ListBox
        ChoosefromfileButton           matlab.ui.control.Button
        ImagePreviewLabel              matlab.ui.control.Label
        ImageWindowButton              matlab.ui.control.StateButton
        ProgressSliderLabel            matlab.ui.control.Label
        ProgressSlider                 matlab.ui.control.Slider
        EvolveButton                   matlab.ui.control.Button
        MaxIterationsEditFieldLabel    matlab.ui.control.Label
        MaxIterationsEditField         matlab.ui.control.NumericEditField
        ContractionBiasEditFieldLabel  matlab.ui.control.Label
        ContractionBiasEditField       matlab.ui.control.NumericEditField
        SmoothFactorEditFieldLabel     matlab.ui.control.Label
        SmoothFactorEditField          matlab.ui.control.NumericEditField
        HistogramEqualizationCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Paths = Paths(convertCharsToStrings(pwd)); % Class of all possible paths and images
        AlgoParams = struct( 'Mask' , [] , 'SmoothFactor' , [] , 'ContractionBias' , [] ,'MaxNumIteration' , 1000 , ...
                             'AlgorithmFunction' , AvailableAlgorithms.MatlabBuiltIn , 'MatlabFunctionMethod' , AvailableAlgorithms.MatlabBuiltInMethods.Edge );
        AnimationControl = struct('NumIterationsPerUpdate' , 2)
        Images = struct('OriginalImage' , [] , 'Image2Show' , [] , 'CroppedImage' , [] , 'GreyImage' , []);
        WindowsManager ; % Manges and controls all open apps and windows
        ApearanceDefaultVals = struct('DefaultButtonColor' , [0.96, 0.96,0.96] , ...
                                      'ActiveButtonColor'  , [0.5 , 0.5 ,0   ] , ...
                                      'WaitingButtonColor' , [0.2 , 0.6 , 1  ] ,...
                                      'MaskColor' , [0,1,0]);
    end
    
    properties (Access = public)
        is_busy_evolving = false;
    end
    
    methods (Access = private)
        
        function [] = read_user_chosen_image(app)
            chosen_image_str = app.ImageFromlist.Value;
            chosen_image_path = app.Paths.AvailablePictures.(chosen_image_str);
            OriginalImage = imread(chosen_image_path);
            app.Images.OriginalImage = OriginalImage;
            app.Images.CroppedImage  = OriginalImage;
            app.Images.GreyImage     = OriginalImage;
            app.Images.Image2Show    = OriginalImage;
        end % read_user_chosen_image
        
        
        function [] = update_data_with_user_selection(app , WhatWasUpdated_str)   
            %WhatWasUpdated_str=     AlgorithmFunction   , MatlabFunctionMethod ,  ContractionBias  , SmoothFactor
            
            %WhatWasUpdated_str=='AlgorithmFunction'
            app.AlgoParams.AlgorithmFunction= AvailableAlgorithms.( app.AlgorithmDropDown.Value );
            %WhatWasUpdated_str=='MatlabFunctionMethod'
            app.AlgoParams.MatlabFunctionMethod =  AvailableAlgorithms.MatlabBuiltInMethods.( app.MethodButtonGroup.SelectedObject.Text );
            %WhatWasUpdated_str=='ContractionBias'
            app.AlgoParams.ContractionBias = app.ContractionBiasEditField.Value;
            %WhatWasUpdated_str=='SmoothFactor'
            app.AlgoParams.SmoothFactor = app.SmoothFactorEditField.Value;
            
            
        end % update_data_with_user_selection
        
    end %private
    
    
    methods (Access = public) 
        
        function [] = update_image2show(app , image)
            %update image locally:
            app.Images.Image2Show = image;
            
            %Update figures:
            imshow( app.Images.Image2Show , 'Parent' , app.UIAxes);
            app.UIAxes.XLabel.Visible = "off";
            app.UIAxes.YLabel.Visible = "off";
            app.UIAxes.Title.Visible  = "off";
            
            %Update at distant window app, if exist
            if app.WindowsManager.has_ImageWindow
                app.WindowsManager.ImageWindow.update_image2show(app.Images.Image2Show);
            end
        end


        function NewImage = mask_on_image(app)  
            NewImage = add_mask( app.Images.GreyImage ,app.AlgoParams.Mask , app.ApearanceDefaultVals.MaskColor );
        end
    end %public
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Continue creating the app's properties:
            app.WindowsManager = WindowsManagerClass(app) ; % Manges and controls all open apps and windows
            
            %Available Algorithms and Images:
            app.AlgorithmDropDown.Items = AvailableAlgorithms.string_cell_array();
            app.ImageFromlist.Items = app.Paths.AvailablePictures_string_cell_array();


            %Take care of images:            
            app.read_user_chosen_image();
            %start showing stuff:
            app.update_image2show( app.Images.OriginalImage );
        end

        % Value changed function: ImageWindowButton
        function ImageWindowButtonValueChanged(app, event)
            app.WindowsManager.show_image_window();
            app.update_image2show( app.Images.Image2Show )
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            app.WindowsManager.close("MainApp");
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            persistent is_waiting_for_second_press  roi
            if isempty(is_waiting_for_second_press)
                is_waiting_for_second_press = false;
                roi = [];
            end
            
            if is_waiting_for_second_press
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.DefaultButtonColor;
                NewCroppedImage = imcrop(app.Images.CroppedImage , roi.Position );                
                %Reset for next crop attempts:
                is_waiting_for_second_press = false;
                delete(roi);
                %Update images:
                app.Images.CroppedImage = NewCroppedImage;
                try
                    app.Images.GreyImage    = rgb2gray(NewCroppedImage);
                catch
                    app.Images.GreyImage    = NewCroppedImage;
                end
                app.update_image2show(NewCroppedImage);
            else
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.ActiveButtonColor;
                roi = drawrectangle(app.UIAxes);               
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.WaitingButtonColor;
                is_waiting_for_second_press = true;
            end
            
        end

        % Value changed function: ImageFromlist
        function ImageFromlistValueChanged(app, event)
            app.read_user_chosen_image();
            app.update_image2show(app.Images.OriginalImage);
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            app.ROIButton.BackgroundColor = app.ApearanceDefaultVals.ActiveButtonColor;
            roi = drawrectangle(app.UIAxes); 
            app.AlgoParams.Mask = roi.createMask();
            delete(roi);
            app.ROIButton.BackgroundColor = app.ApearanceDefaultVals.DefaultButtonColor;
            % Update image to show   with the new roi:
            NewImage = app.mask_on_image();
            app.update_image2show(NewImage);
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            app.update_data_with_user_selection('MatlabFunctionMethod');
        end

        % Button pushed function: EvolveButton
        function EvolveButtonPushed(app, event)
            app.update_data_with_user_selection();
            
            if app.is_busy_evolving 
                user_asked_to_stop=true;          
            else        
                user_asked_to_stop = false;        
            end
            
            app.is_busy_evolving = true;
            
            for i = 1: app.AlgoParams.MaxNumIteration / app.AnimationControl.NumIterationsPerUpdate
                
                app.AlgoParams.Mask = activecontour(app.Images.GreyImage , app.AlgoParams.Mask , app.AlgoParams.MatlabFunctionMethod , ...
                                                 'SmoothFactor'    , app.AlgoParams.SmoothFactor   ,...
                                                 'ContractionBias' , app.AlgoParams.ContractionBias);
                                             
                if user_asked_to_stop             
                    break;
                else
                    ImageWithMask = mask_on_image(app);
                    app.update_image2show(ImageWithMask);
                    drawnow
                end
                
            end
            
            
            app.is_busy_evolving = false;
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            app.update_data_with_user_selection('AlgorithmFunction');
        end

        % Value changed function: SmoothFactorEditField
        function SmoothFactorEditFieldValueChanged(app, event)
            app.update_data_with_user_selection('SmoothFactor');
        end

        % Value changed function: ContractionBiasEditField
        function ContractionBiasEditFieldValueChanged(app, event)
            app.update_data_with_user_selection('ContractionBias')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Active Contours App ';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.Menu = uimenu(app.MainWindow);
            app.Menu.Text = '....';
            app.AlgorithmDropDownLabel = uilabel(app.MainWindow);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.MainWindow);
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.UIAxes = uiaxes(app.MainWindow);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MethodButtonGroup = uibuttongroup(app.MainWindow);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.RegionButton = uiradiobutton(app.MethodButtonGroup);
            app.RegionButton.Text = 'Region';
            app.RegionButton.Value = true;
            app.EdgeButton = uiradiobutton(app.MethodButtonGroup);
            app.EdgeButton.Text = 'Edge';
            app.CropButton = uibutton(app.MainWindow, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ROIButton = uibutton(app.MainWindow, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.ImageFromlistListBoxLabel = uilabel(app.MainWindow);
            app.ImageFromlistListBoxLabel.Text = 'Image (From list)';
            app.ImageFromlist = uilistbox(app.MainWindow);
            app.ImageFromlist.ValueChangedFcn = createCallbackFcn(app, @ImageFromlistValueChanged, true);
            app.ChoosefromfileButton = uibutton(app.MainWindow, 'push');
            app.ChoosefromfileButton.Text = 'Choose from file';
            app.ImagePreviewLabel = uilabel(app.MainWindow);
            app.ImagePreviewLabel.Text = 'Image Preview';
            app.ImageWindowButton = uibutton(app.MainWindow, 'state');
            app.ImageWindowButton.ValueChangedFcn = createCallbackFcn(app, @ImageWindowButtonValueChanged, true);
            app.ImageWindowButton.Text = 'Image Window';
            app.ProgressSliderLabel = uilabel(app.MainWindow);
            app.ProgressSliderLabel.Enable = 'off';
            app.ProgressSliderLabel.Text = 'Progress';
            app.ProgressSlider = uislider(app.MainWindow);
            app.ProgressSlider.Enable = 'off';
            app.EvolveButton = uibutton(app.MainWindow, 'push');
            app.EvolveButton.ButtonPushedFcn = createCallbackFcn(app, @EvolveButtonPushed, true);
            app.EvolveButton.Text = 'Evolve';
            app.MaxIterationsEditFieldLabel = uilabel(app.MainWindow);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.MainWindow, 'numeric');
            app.MaxIterationsEditField.Value = 1000;
            app.ContractionBiasEditFieldLabel = uilabel(app.MainWindow);
            app.ContractionBiasEditFieldLabel.Text = 'Contraction Bias';
            app.ContractionBiasEditField = uieditfield(app.MainWindow, 'numeric');
            app.ContractionBiasEditField.ValueChangedFcn = createCallbackFcn(app, @ContractionBiasEditFieldValueChanged, true);
            app.ContractionBiasEditField.Value = 0.01;
            app.SmoothFactorEditFieldLabel = uilabel(app.MainWindow);
            app.SmoothFactorEditFieldLabel.Text = 'Smooth Factor';
            app.SmoothFactorEditField = uieditfield(app.MainWindow, 'numeric');
            app.SmoothFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothFactorEditFieldValueChanged, true);
            app.SmoothFactorEditField.Value = 4;
            app.HistogramEqualizationCheckBox = uicheckbox(app.MainWindow);
            app.HistogramEqualizationCheckBox.Text = {'{Histogram ,'; 'Equalization}'};
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ActiveContoursApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end